classdef IntegrationApplet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ConnectionLamp              matlab.ui.control.Lamp
        LPFWeightingEditField       matlab.ui.control.NumericEditField
        LPFWeightingEditFieldLabel  matlab.ui.control.Label
        RollEditField               matlab.ui.control.NumericEditField
        RollEditFieldLabel          matlab.ui.control.Label
        PitchEditField              matlab.ui.control.NumericEditField
        PitchEditFieldLabel         matlab.ui.control.Label
        EndButton                   matlab.ui.control.Button
        COMPORTDropDown             matlab.ui.control.DropDown
        COMPORTDropDownLabel        matlab.ui.control.Label
        AddFilterButton             matlab.ui.control.Button
        SetupButton                 matlab.ui.control.Button
        StreamButton                matlab.ui.control.Button
        SetupLamp                   matlab.ui.control.Lamp
        FilterLamp                  matlab.ui.control.Lamp
        StartLamp                   matlab.ui.control.Lamp
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    % Variables that the whole program can access
    properties (Access = private)
        SelectedComPort
        myArduino
        IsEndButtonPushed
        myDataPoints
        myAngles
        myPlot
        myFilteredAngles
        IsFilterButtonPushed
        myFilterWeighting
    end
    
    methods (Access = private)
            
        % Data Streaming Function
        % Description:
        % Used to get csv data from Serial and convert 3D spatial data
        function DataStreaming(app)
            
            % Only Run if the End button is NOT pushed
            while (app.IsEndButtonPushed == false)
                        
                     % Read one line from Arduino through Serial
                     inputLine = readline(app.myArduino);
                     
                     % Split the CSV values into measurements
                     dataPoints = split(inputLine, ',');
                     app.myDataPoints(1) = str2double(dataPoints(1));
                     app.myDataPoints(2) = str2double(dataPoints(2));
                     app.myDataPoints(3)  = str2double(dataPoints(3)); 
                     
                     % Calculate the Euler Angles using measurements
                     CalculateAngles(app);
                     
                     % If Filter button is pushed, use filtered angles
                     % instead for the vizualiser
                     if(app.IsFilterButtonPushed == true)
                         
                         % Recursive Low Pass Filter function
                         LowPassFilter(app);
                         
                         % Set visualizer angle to be filtered values
                         app.myAngles(1) = app.myFilteredAngles(1);
                         app.myAngles(2) = app.myFilteredAngles(2);
                         app.myAngles(3) = app.myFilteredAngles(3);
                     end
                     
                     % Rotate the vizualizer object
                     RotateBody(app);
                     
                     % Perform system handshake with Arduino to send next
                     % set of Serial Data.
                     writeline(app.myArduino, 'c');
                     
            end
        end
        
        % Function to Calculate Euler Angles
        function CalculateAngles(app)
            
                  % Use Formula in Readme.md reference
                  app.myAngles(1) = atan2(app.myDataPoints(1), sqrt(app.myDataPoints(2)^2 + app.myDataPoints(3)^2)); % pitch (rad)
                  app.myAngles(2) = atan2(app.myDataPoints(2), sqrt(app.myDataPoints(1)^2 + app.myDataPoints(3)^2)); % roll (rad)
                  app.myAngles(3) = atan2(sqrt(app.myDataPoints(1)^2 + app.myDataPoints(2)^2) , app.myDataPoints(3)); % yaw (rad)
                  
                  % Edit the Pitch and Roll numeric fields.
                  app.PitchEditField.Value = rad2deg(app.myAngles(1));
                  app.RollEditField.Value = rad2deg(app.myAngles(2));
        end
        
        
        % Function to plot and rotate the vizualiser model.
        function RotateBody(app)
                    
                    % Plotting a basic rectangle with triangle to point
                    % orientation
                    x1 = 2;
                    x2 = -2;
                    y1 = -2;
                    y2 = 2;
                    z = 0;  
                    yt1 = 0;
                    xt1 = -1;
                    app.myPlot = plot3(app.UIAxes, [x1 x2 x2 x1 x1 xt1 x1], [y1 y1 y2 y2 y1 yt1 y2], [z z z z z z z]);  
                    axis(app.UIAxes, [-3 3 -3 3 -3 3]);
                    view(app.UIAxes,30,30);
                    
                    % Rotate Euler Angle 1 on Y axis
                    r = [0,1,0];
                    rotate(app.myPlot, r, rad2deg(app.myAngles(1)));
                    
                    % Rotate Euler Angle 2 on X axis
                    r = [-1, 0, 0];
                    rotate(app.myPlot, r, rad2deg(app.myAngles(2)));
                    
                    % Rotating Euler Angle 3 on Z axis is hard without
                    % Gyroscope so is not included.
                    % r = [0, 0, 1];
                    % rotate(app.myPlot, r, rad2deg(app.myAngles(3)));  
                    drawnow
        end
        
        
        % Function to do recursive Low Pass Filter of angles
        % Textbook Reference in Readme.md 
        function LowPassFilter(app)
            
            x = app.myAngles(1); % pitch(1)
            y = app.myAngles(2); % roll(2)
            z = app.myAngles(3); % yaw (3)
            
            persistent previousX;
            persistent previousY;
            persistent previousZ;
            persistent firstRun;
            
            if( isempty(firstRun))
                previousX = x;
                previousY = y;
                previousZ = z;
                firstRun = 1;
            end
            
            alpha = app.myFilterWeighting;
            app.myFilteredAngles(1) = alpha * previousX + (1-alpha)*x;
            app.myFilteredAngles(2) = alpha * previousY + (1-alpha)*y;
            app.myFilteredAngles(3) = alpha * previousZ + (1-alpha)*z;
            
            previousX = app.myFilteredAngles(1);
            previousY = app.myFilteredAngles(2);
            previousZ = app.myFilteredAngles(3);   
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: COMPORTDropDown
        function COMPORTDropDownValueChanged(app, event)
            % Choose COM Port
            app.SelectedComPort = app.COMPORTDropDown.Value;
            
            % If COM Port chosen matches with available device, activate
            % light
            if(serialportlist == app.SelectedComPort)
                app.ConnectionLamp.Color = 'g';
            end
        end

        % Button pushed function: StreamButton
        function StreamButtonPushed(app, event)
            % If streaming button is pushed, activate DataStreaming
            % function
            app.StartLamp.Color = 'g';  
            DataStreaming(app);
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            % Close program if End Button is pushed/
            app.IsEndButtonPushed = true;
            clear 
            close all
            clc
            instrreset
            closereq(); 
            return   
        end

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
              % On Setup, initialize all variable values
              app.IsEndButtonPushed = false;
              app.IsFilterButtonPushed = false;
              app.myFilterWeighting = 0;
              
              % Start communication with device through selected com port
              app.myArduino = serialport(app.SelectedComPort, 9600);
              flush(app.myArduino);
              app.SetupLamp.Color = 'g';
              
        end

        % Button pushed function: AddFilterButton
        function AddFilterButtonPushed(app, event)
                   app.IsFilterButtonPushed = true;
                   app.FilterLamp.Color = 'g';
        end

        % Value changed function: LPFWeightingEditField
        function LPFWeightingEditFieldValueChanged(app, event)
            app.myFilterWeighting = app.LPFWeightingEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vizualiser')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartLamp = uilamp(app.UIFigure);
            app.FilterLamp = uilamp(app.UIFigure);
            app.SetupLamp = uilamp(app.UIFigure);
            app.StreamButton = uibutton(app.UIFigure, 'push');
            app.StreamButton.ButtonPushedFcn = createCallbackFcn(app, @StreamButtonPushed, true);
            app.StreamButton.Text = 'Stream';
            app.SetupButton = uibutton(app.UIFigure, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Setup';
            app.AddFilterButton = uibutton(app.UIFigure, 'push');
            app.AddFilterButton.ButtonPushedFcn = createCallbackFcn(app, @AddFilterButtonPushed, true);
            app.AddFilterButton.Text = 'Add Filter';
            app.COMPORTDropDownLabel = uilabel(app.UIFigure);
            app.COMPORTDropDownLabel.Text = 'COM PORT';
            app.COMPORTDropDown = uidropdown(app.UIFigure);
            app.COMPORTDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13', 'COM14'};
            app.COMPORTDropDown.ValueChangedFcn = createCallbackFcn(app, @COMPORTDropDownValueChanged, true);
            app.COMPORTDropDown.Value = 'COM1';
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField.Editable = 'off';
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField.Editable = 'off';
            app.LPFWeightingEditFieldLabel = uilabel(app.UIFigure);
            app.LPFWeightingEditFieldLabel.Text = {'LPF '; 'Weighting'};
            app.LPFWeightingEditField = uieditfield(app.UIFigure, 'numeric');
            app.LPFWeightingEditField.Limits = [0 1];
            app.LPFWeightingEditField.ValueChangedFcn = createCallbackFcn(app, @LPFWeightingEditFieldValueChanged, true);
            app.ConnectionLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IntegrationApplet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end