classdef BMO055 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RollAngleEditField        matlab.ui.control.NumericEditField
        RollAngleEditFieldLabel   matlab.ui.control.Label
        YawAngleEditField_2       matlab.ui.control.NumericEditField
        YawAngleEditField_2Label  matlab.ui.control.Label
        YawAngleEditField         matlab.ui.control.NumericEditField
        YawAngleEditFieldLabel    matlab.ui.control.Label
        COMPortValidLamp          matlab.ui.control.Lamp
        Label_2                   matlab.ui.control.Label
        TerminateAppButton        matlab.ui.control.Button
        BeginStreamingLamp        matlab.ui.control.Lamp
        Lamp_2Label               matlab.ui.control.Label
        BeginStreamingButton      matlab.ui.control.Button
        Connect2ArdunioLamp       matlab.ui.control.Lamp
        Label                     matlab.ui.control.Label
        Connect2ArduinoButton     matlab.ui.control.Button
        COMPortDropDown           matlab.ui.control.DropDown
        COMPortDropDownLabel      matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    % ALL GLOBAL SCOPE VARIABLES ARE DECLARED HERE
    properties (Access = private)
        SelectedCOMPort
        ConnectedArduino
        IsTerminateAppPushed
        RawQuaternion
        RotationMatrix
        InitialMatrix
    end
    
    % ALL FUNCTIONS ARE DECLARED HERE
    methods (Access = private)
        
        % Data Streaming Function (Main Loop)
        function data_streaming(app)
            
            while(app.IsTerminateAppPushed == false)
                
                 % Read one line from Arduino through Serial
                     InputCSVLine = readline(app.ConnectedArduino);
                     RawData = split(InputCSVLine, ',');
                     
                     % Quaternion (w, x, y, z)
                     app.RawQuaternion(1) = str2double(RawData(1));
                     app.RawQuaternion(2) = str2double(RawData(2));
                     app.RawQuaternion(3) = str2double(RawData(3));
                     app.RawQuaternion(4) = str2double(RawData(4));
                     
                     QuatVector = [app.RawQuaternion(1) app.RawQuaternion(2) app.RawQuaternion(3) app.RawQuaternion(4)];
                     app.RotationMatrix = quat2rotm(QuatVector);
                     
                     % Rotate Body According to Quaternion
                     rotate_body(app);
                     
                     writeline(app.ConnectedArduino, 'c');
            end
            
        end
        
        % Rotate Body and Plot
        function rotate_body(app)
                     
                     A = [0 0 0];
                     B = [3 0 0];
                     C = [0 3 0];
                     D = [0 0 3];
                     E = [0 3 3];
                     F = [3 0 3];
                     G = [3 3 0];
                     H = [3 3 3];
                     P = [A;B;F;H;G;C;A;D;E;H;F;D;E;C;G;B];
                     
%                      xpoints = [1, 1, -1, -1, 1, 1, 1, -1, -1, 1];
%                      ypoints = [1, -1, -1, 1, 1, 1, -1, -1, 1, 1];
%                      zpoints = [0, 0, 0, 0, 0, 1, 1, 1, 1,1];
                     P = P * app.RotationMatrix;
                     plot3(app.UIAxes, P(:,1),P(:,2),P(:,3));
%                     plot3(app.UIAxes, xpoints, ypoints, zpoints);
                    axis(app.UIAxes, [-4 4 -4 4 -4 4]);
                    view(app.UIAxes,-225,30);
                                                   
                    drawnow
            
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: COMPortDropDown
        function COMPortDropDownValueChanged(app, event)
            app.SelectedCOMPort = app.COMPortDropDown.Value;
            
            myConnectedDevices = serialportlist;
       
            for i = 1:length(myConnectedDevices)
                if ( app.SelectedCOMPort == myConnectedDevices(i) ) 
                    app.COMPortValidLamp.Color = 'g';
                else
                    app.COMPortValidLamp.Color = 'r';
                end
            end
            
        end

        % Button pushed function: Connect2ArduinoButton
        function Connect2ArduinoButtonPushed(app, event)
            app.ConnectedArduino = serialport(app.SelectedCOMPort, 115200);
            flush(app.ConnectedArduino);
            app.Connect2ArdunioLamp.Color = 'g';
            app.IsTerminateAppPushed = false;
            
        end

        % Button pushed function: BeginStreamingButton
        function BeginStreamingButtonPushed(app, event)
            app.BeginStreamingLamp.Color = 'g'; 
            data_streaming(app);
        end

        % Button pushed function: TerminateAppButton
        function TerminateAppButtonPushed(app, event)
            app.IsTerminateAppPushed = true;
            clear 
            close all
            clc
            instrreset
            closereq(); 
            return   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Visualizer')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.COMPortDropDownLabel = uilabel(app.UIFigure);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.UIFigure);
            app.COMPortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12'};
            app.COMPortDropDown.ValueChangedFcn = createCallbackFcn(app, @COMPortDropDownValueChanged, true);
            app.COMPortDropDown.Value = 'COM1';
            app.Connect2ArduinoButton = uibutton(app.UIFigure, 'push');
            app.Connect2ArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @Connect2ArduinoButtonPushed, true);
            app.Connect2ArduinoButton.Text = {'Connect to Arduino'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Connect2ArdunioLamp = uilamp(app.UIFigure);
            app.BeginStreamingButton = uibutton(app.UIFigure, 'push');
            app.BeginStreamingButton.ButtonPushedFcn = createCallbackFcn(app, @BeginStreamingButtonPushed, true);
            app.BeginStreamingButton.Text = {'Begin Streaming'; ''};
            app.Lamp_2Label = uilabel(app.UIFigure);
            app.Lamp_2Label.Text = '';
            app.BeginStreamingLamp = uilamp(app.UIFigure);
            app.TerminateAppButton = uibutton(app.UIFigure, 'push');
            app.TerminateAppButton.ButtonPushedFcn = createCallbackFcn(app, @TerminateAppButtonPushed, true);
            app.TerminateAppButton.Text = 'End Program';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.COMPortValidLamp = uilamp(app.UIFigure);
            app.YawAngleEditFieldLabel = uilabel(app.UIFigure);
            app.YawAngleEditFieldLabel.Text = 'Yaw Angle';
            app.YawAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawAngleEditField_2Label = uilabel(app.UIFigure);
            app.YawAngleEditField_2Label.Text = 'Yaw Angle';
            app.YawAngleEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RollAngleEditFieldLabel = uilabel(app.UIFigure);
            app.RollAngleEditFieldLabel.Text = 'Roll Angle';
            app.RollAngleEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BMO055

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end